classdef echo_receiver_terminal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        CRCLabel               matlab.ui.control.Label
        DemodsucsessLamp       matlab.ui.control.Lamp
        DemodsucsessLampLabel  matlab.ui.control.Label
        ErrorscorrectedLabel   matlab.ui.control.Label
        CommandIDLabel         matlab.ui.control.Label
        DeviceIDLabel          matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        IdleLabel              matlab.ui.control.Label
        DetectedLabel          matlab.ui.control.Label
        SyncronizedLabel       matlab.ui.control.Label
        DemodpassLabel         matlab.ui.control.Label
        SM_Gauge               matlab.ui.control.LinearGauge
        StartButton            matlab.ui.control.StateButton
        RightPanel             matlab.ui.container.Panel
        UIAxes_freq            matlab.ui.control.UIAxes
        UIAxes_time            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        sample_freq = 44100;
        continue_rx = false;
        rec_obj;
        file_cntr = 0;
    end

    methods (Access = private)
        

        function main(app)
            app.file_cntr = app.file_cntr+1;
            deviceReader = audioDeviceReader(44100,4096);
            fileWriter = dsp.AudioFileWriter(SampleRate=deviceReader.SampleRate,Filename=...
                "C:\ToDATA\audio modem project\matlab\matlab code\receiver\AppDesigner echo terminal\audio_stream_files\audio_stream_" + num2str(app.file_cntr)+ ".wav");   
            if(app.continue_rx) % perform setup for the receiver object
                new_input_buffer = deviceReader();
                fileWriter(new_input_buffer);
                [~, ~, ~, app.rec_obj] = echo_receiver(new_input_buffer, [], 1);
            end
            while(app.continue_rx)
                new_input_buffer = deviceReader();
                fileWriter(new_input_buffer);
                app.update_plots(new_input_buffer);
                [reset_audio_flag] = app.echo_receiver_handler(new_input_buffer);
                if(reset_audio_flag)
                    deviceReader.release();
                    delete(deviceReader);
                    return;
                end
            end
        end
        
        function update_plots(app, signal)
            plot(app.UIAxes_time,signal);
            ylim(app.UIAxes_time,[-0.75 0.75]);
            [fft_data, fft_axis] = fft_w_axis(signal,app.sample_freq);
            plot(app.UIAxes_freq,fft_axis, abs(fft_data));
            ylim(app.UIAxes_freq,[0 50]);
            drawnow;
        end
        
        function [reset_audio_reader_flag] = echo_receiver_handler(app, input_samples)
            reset_audio_reader_flag = false;
            % tic;
            [output_bits, error_vector, logged_state, app.rec_obj] = echo_receiver(input_samples, app.rec_obj, 0);
            % process_time = toc;
            % disp("sm log:" + num2str(app.rec_obj.sm_log));
            switch logged_state
                case 0 % SM at Idle
                    app.SM_Gauge.Value = 0;
                    % app.clear_display_data();
                case 1 % SM at signal detected
                    app.SM_Gauge.Value = 1;
                    app.clear_display_data();
                case 2 % SM at signal syncronized, check if demoded
                    if (error_vector == -2) % SM at signal syncronized, check if at demod
                        app.SM_Gauge.Value = 2;
                        app.clear_display_data();
                    elseif(error_vector == -1) % demod failed
                        app.SM_Gauge.Value = 3;
                        app.set_display_data(false, [], [], [], []);
                        reset_audio_reader_flag = true;
                    else % demod completed
                        app.SM_Gauge.Value = 3;
                        [device_id, command_id, crc_flag] = app.rx_bits_parser(output_bits);
                        app.set_display_data(true, error_vector, device_id, command_id, crc_flag);
                        reset_audio_reader_flag = true;
                    end
            end

        end

        function clear_display_data(app)
            app.DemodsucsessLamp.Color = '#595959';
            app.ErrorscorrectedLabel.Text = 'Errors corrected:';
            app.DeviceIDLabel.Text = 'Device ID:';
            app.CommandIDLabel.Text = 'Command ID:';
            app.CRCLabel.Text = 'CRC:';
        end

        function set_display_data(app, demod_sucsess_flag, errors, device_id, command_id, crc_flag)
            if (demod_sucsess_flag == false)
                app.DemodsucsessLamp.Color = 'red';
                app.ErrorscorrectedLabel.Text = 'Errors corrected:';
                app.DeviceIDLabel.Text = 'Device ID:';
                app.CommandIDLabel.Text = 'Command ID:';
                app.CRCLabel.Text = 'CRC:';
            else
                errors_corrected_string = "Errors corrected: " + num2str(errors);
                device_id_string = "Device ID: " + num2str(device_id);
                command_id_string = "Command ID: " + num2str(command_id);
                if (crc_flag)
                    crc_flag_string = "CRC: Pass";
                    app.DemodsucsessLamp.Color = 'green';
                else
                    crc_flag_string = "CRC: Fail";
                    app.DemodsucsessLamp.Color = 'red';

                end
                app.ErrorscorrectedLabel.Text = errors_corrected_string;
                app.DeviceIDLabel.Text = device_id_string;
                app.CommandIDLabel.Text = command_id_string;
                app.CRCLabel.Text = crc_flag_string; 
            end
        end
        
        function [device_id, command_id, crc_flag] = rx_bits_parser(app, rx_bits)
            device_id = 2.^(15:-1:0)*rx_bits(1:16);
            command_id = 2.^(15:-1:0)*rx_bits(17:32);
            crc_4 = comm.CRCGenerator([1 1 1 1 1]);
            expected_crc = crc_4(rx_bits(1:32));
            expected_crc = expected_crc(33:36);
            if(expected_crc == rx_bits(33:36))
                crc_flag = true;
            else
                crc_flag = false;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.audio_reader = audioDeviceReader;
            app.clear_display_data();

        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            app.clear_display_data();
            if(value) % start recording
                app.StartButton.Text = "Stop";
                app.continue_rx = true;
                app.main();
                while(value) % recall funciton for stability reasons
                    pause(1);
                    app.main();
                end
            else % stop recording
                app.StartButton.Text = "Start";
                app.continue_rx = false;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {481, 481};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {325, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartButton = uibutton(app.LeftPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.SM_Gauge = uigauge(app.LeftPanel, 'linear');
            app.SM_Gauge.Limits = [0 3];
            app.SM_Gauge.MajorTicks = [0 1 2 3];
            app.SM_Gauge.MinorTicks = [];
            app.DemodpassLabel = uilabel(app.LeftPanel);
            app.DemodpassLabel.Text = 'Demod pass';
            app.SyncronizedLabel = uilabel(app.LeftPanel);
            app.SyncronizedLabel.Text = 'Syncronized';
            app.DetectedLabel = uilabel(app.LeftPanel);
            app.DetectedLabel.Text = 'Detected';
            app.IdleLabel = uilabel(app.LeftPanel);
            app.IdleLabel.Text = 'Idle';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.DeviceIDLabel = uilabel(app.LeftPanel);
            app.DeviceIDLabel.Text = 'Device ID:';
            app.CommandIDLabel = uilabel(app.LeftPanel);
            app.CommandIDLabel.Text = 'Command ID: ';
            app.ErrorscorrectedLabel = uilabel(app.LeftPanel);
            app.ErrorscorrectedLabel.Text = 'Errors corrected:';
            app.DemodsucsessLampLabel = uilabel(app.LeftPanel);
            app.DemodsucsessLampLabel.Text = 'Demod sucsess';
            app.DemodsucsessLamp = uilamp(app.LeftPanel);
            app.CRCLabel = uilabel(app.LeftPanel);
            app.CRCLabel.Text = 'CRC:';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_time = uiaxes(app.RightPanel);
            title(app.UIAxes_time, 'Audio input time domain')
            xlabel(app.UIAxes_time, 'Time')
            app.UIAxes_freq = uiaxes(app.RightPanel);
            title(app.UIAxes_freq, 'Audio input freq domain')
            xlabel(app.UIAxes_freq, 'Freq')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = echo_receiver_terminal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end